classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MinesweeperUIFigure            matlab.ui.Figure
        WelcometoMinesweeperLabel      matlab.ui.control.Label
        panel1                         matlab.ui.container.Panel
        Minefield                      matlab.ui.container.GridLayout
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

   %%%%%%%%%%%%%%%%%%%%%%%NOTE%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
   %IMAGE SOURCES ARE USED ON A PERSONAL DEVICE, YOU NEED TO CHANGE TO YOUR
   %OWN PATH%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    
    properties (Access = private)
        countMines double;  % Counter for the number of mines
        counter double;      % Counter for the remaining bombs
   end


    methods (Access = public)


         % Method to generate the Minesweeper game board
        function generateBomb(app, minefield, difficulty)
            % Initialize properties
            app.countMines = '';
            app.counter = difficulty;

    % Calculate number of buttons based on field size
            windowSize = app.MinesweeperUIFigure.Position;
            nButtons = numel(minefield);
    
    % Initialize button matrix
    buttons = cell(size(minefield));
    app.panel1.Visible='on';
    % Create and update the counter label
            h = findall(app.MinesweeperUIFigure, 'Tag', 'counter');
            delete(h);
            label = uilabel(app.MinesweeperUIFigure, 'Position', [170, 510, 400, 60], ...
                'FontSize', 18, 'Tag', 'counter');
            label.Text = 'Bombs remaining: ';   
    
    % Create Smiley button
            uibutton(app.MinesweeperUIFigure, ...
                "Position", [500, 610, 60, 60], ...
                "Text", '', ...
                "Tag", 'Smiley', ...
                "Icon", "C:\Users\Administrator\Desktop\Minesweeper\images\smiley-face.png");

            % Create flag placement checkbox
            uicheckbox(app.MinesweeperUIFigure, "Text", 'Place flag', 'Position', [920, 510, 300, 60], ...
                'FontSize', 24, 'FontWeight', 'bold', 'Tag', 'flagPlacer', 'Value', 0);

            % Set the position of the game panel based on the difficulty
            if difficulty == 99
                minefield = minefield';
                app.panel1.Position = [110, 40, 30 * size(minefield, 2), 30 * size(minefield, 1)];
            elseif difficulty == 40
                app.panel1.Position = [290, 40, 30 * size(minefield, 1), 30 * size(minefield, 2)];
            else
                app.panel1.Position = [410, windowSize(2), 30 * size(minefield, 1), 30 * size(minefield, 2)];
            end
    
    % Calculate button dimensions based on field size
            buttonWidth = 30;
            buttonHeight = 30;
    
   % Create buttons and set their properties
            for i = 1:size(minefield, 1)
                for j = 1:size(minefield, 2)
                    buttons{i, j} = uibutton(app.panel1, ...
                        "BackgroundColor", [0.7969, 0.7969, 0.7969], ...
                        "IconAlignment", "center", ...
                        "Icon", 'C:\Users\Administrator\Desktop\Minesweeper\images\square.png', ...
                        "UserData", [i, j]);

                    % Set button position
                    buttons{i, j}.Position = [j * buttonWidth - 30, i * buttonHeight - 30, buttonWidth, buttonHeight];
                    buttons{i, j}.BackgroundColor = [0.7969, 0.7969, 0.7969];
                    % Set button text (initially empty)
                    buttons{i, j}.Text = "";

                end
            end
    % Set callback function for each button
            for i = 1:size(minefield, 1)
                for j = 1:size(minefield, 2)
                    buttons{i, j}.ButtonPushedFcn = @(src, event) buttonClicked(app, src, buttons, label);
                end
            end
      
            % Randomly place bombs on the game board
            bombLocations = randperm(nButtons, difficulty);
            app.countMines = 0;

    
     for i = 1:length(bombLocations)
                app.countMines = app.countMines + 1;
                buttonIndex = bombLocations(i);
                [row, col] = ind2sub(size(minefield), buttonIndex);
                minefield(row, col) = -1;
                buttons{row, col}.Tag = 'bomb';
     end
    
   % Calculate and set the numbers around bombs
    
    for i = 1:size(minefield,1)
        for j = 1:size(minefield,2)
            if strcmp(buttons{i, j}.Tag, '')
               if (i > 1 && i < size(minefield,1) && j > 1 && j < size(minefield,2))
                count = strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                        strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + ...
                        strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb');
               elseif (i == 1 && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == 1 && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == size(minefield,2))
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == size(minefield,1) && j == 1)
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i-1, j}.Tag, 'bomb');
               elseif (i == 1)
                   count = strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j+1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j}.Tag, 'bomb') + strcmp(buttons{i+1, j-1}.Tag, 'bomb');
               elseif (i == size(minefield,1))
                   count = strcmp(buttons{i, j+1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb');
               elseif (j == 1)
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j+1}.Tag, 'bomb') + strcmp(buttons{i, j+1}.Tag, 'bomb') + ...
                            strcmp(buttons{i+1, j+1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               else
                   count = strcmp(buttons{i-1, j}.Tag, 'bomb') + strcmp(buttons{i-1, j-1}.Tag, 'bomb') + strcmp(buttons{i, j-1}.Tag, 'bomb') + ...
                           strcmp(buttons{i+1, j-1}.Tag, 'bomb') + strcmp(buttons{i+1, j}.Tag, 'bomb');
               end
               buttons{i,j}.Tag = num2str(count);
            end
        end
    end
   
      % Update the counter label
            delete(h);
            label.Text = ['Bombs remaining: ' num2str(app.countMines)];
    end


 % Callback function for when a bomb is clicked
 function youAreDead(app, label)
            btns = findall(app.MinesweeperUIFigure, 'Type', 'uibutton');
            label.Text = 'You lose! Restart to play again';
            label.FontColor = 'red';
            label.FontWeight = 'bold';
            label.FontSize = 24;
            label.Position = [380, 510, 400, 60];

            % Disable button click functions
            for i = 1:numel(btns)
                btns(i).ButtonPushedFcn = '';
            end

            % Change the Smiley button to a dead face
            smileyButton = findall(app.MinesweeperUIFigure, 'Tag', 'Smiley');
            smileyButton(1).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\dead-face.png';
        end

  % Callback function for button click
  function buttonClicked(app, src, buttons, label)
    
    
    % Find the smiley button in the UI and change its icon
    smileyButton = findall(app.MinesweeperUIFigure, 'Tag', 'Smiley');
    smileyButton(1).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\o-face.png';
    pause(0.1);
    smileyButton(1).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\smiley-face.png';

    % Get the row and column information from the source button's UserData
    row = src.UserData(1);
    col = src.UserData(2);

    % Find the flagPlacer checkbox in the UI
    flagPlacer = findall(app.MinesweeperUIFigure, 'Tag', 'flagPlacer');

    % Check if the checkbox is checked
    checkboxClicked = any([flagPlacer.Value]);

    % Handle logic based on the checkbox state
    if checkboxClicked
        % Logic for flag placement
        if strcmp(src.Icon, 'C:\Users\Administrator\Desktop\Minesweeper\images\flag.png')
            app.counter = app.counter + 1;
            src.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\square.png';
            label.Text = ['Bombs remaining: ' num2str(app.counter)];
        else
            app.counter = app.counter - 1;
            src.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\flag.png';
            label.Text = ['Bombs remaining: ' num2str(app.counter)];
        end
    else
        % Logic for non-flag clicks
        if strcmp(src.Tag, 'bomb')
            % Handle bomb click
            disp('Bomb clicked!');
            src.BackgroundColor = [1, 0, 0];
            src.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\wrong-bomb.png';
            youAreDead(app, label);

            % Reveal other bombs
            bombs = findall(app.MinesweeperUIFigure, 'Tag', 'bomb');
            for i = 1:numel(bombs)
                if bombs(i) == src
                    continue;
                end
                pause(0.3);
                bombs(i).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\bomb.png';
            end
        else
            % Check icon and perform appropriate actions
            checkIcon(app, row, col, buttons);
            
            % Check for neighboring empty squares and reveal them
            if src.Tag == '0'
                revealEmpty(app, row, col, buttons);
            end
            
            % Check if the game is won
            rem = findall(app.MinesweeperUIFigure, 'Icon', 'C:\Users\Administrator\Desktop\Minesweeper\images\square.png');
            flags = findall(app.MinesweeperUIFigure, 'Icon', 'C:\Users\Administrator\Desktop\Minesweeper\images\flag.png');
            if numel(rem) + numel(flags) == app.countMines
                % Update UI for winning state
                smileyButton(1).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\cool-face.png';
                label.Text = 'You win! Restart to play again';
                label.FontColor = 'green';
                label.FontWeight = 'bold';
                label.FontSize = 24;
                label.Position = [380, 510, 400, 60];

                % Disable button push functions for all buttons
                btns = findall(app.MinesweeperUIFigure, 'Type', 'uibutton');
                for i = 1:numel(btns)
                    btns(i).ButtonPushedFcn = '';
                end

                % Reveal all remaining squares as flags
                squares = findall(app.MinesweeperUIFigure, 'Icon', 'C:\Users\Administrator\Desktop\Minesweeper\images\square.png');
                for i = 1:numel(squares)
                    squares(i).Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\flag.png';
                end
            end
        end
    end
end
% Function to recursively reveal empty cells and their neighboring cells
% app: the application object
% row, col: coordinates of the current cell
% buttons: a cell array representing the Minesweeper grid

function revealEmpty(app, row, col, buttons)
    % Check the current cell's position to determine neighboring cells
    if (row == 1 && col == 1)
        % Top-left corner case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col + 1}.Tag, 'open') && buttons{row, col + 1}.Tag == '0'
            buttons{row, col + 1}.Tag = 'open';
            revealEmpty(app, row, col + 1, buttons);
        end
        checkIcon(app, row, col + 1, buttons);

        if ~strcmp(buttons{row + 1, col + 1}.Tag, 'open') && buttons{row + 1, col + 1}.Tag == '0'
            buttons{row + 1, col + 1}.Tag = 'open';
            revealEmpty(app, row + 1, col + 1, buttons);
        end
        checkIcon(app, row + 1, col + 1, buttons);

        if ~strcmp(buttons{row + 1, col}.Tag, 'open') && buttons{row + 1, col}.Tag == '0'
            buttons{row + 1, col}.Tag = 'open';
            revealEmpty(app, row + 1, col, buttons);
        end
        checkIcon(app, row + 1, col, buttons);
    elseif (row == 1 && col == size(buttons, 2))
        % Top-right corner case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col - 1}.Tag, 'open') && buttons{row, col - 1}.Tag == '0'
            buttons{row, col - 1}.Tag = 'open';
            revealEmpty(app, row, col - 1, buttons);
        end
        checkIcon(app, row, col - 1, buttons);

        if ~strcmp(buttons{row + 1, col - 1}.Tag, 'open') && buttons{row + 1, col - 1}.Tag == '0'
            buttons{row + 1, col - 1}.Tag = 'open';
            revealEmpty(app, row + 1, col - 1, buttons);
        end
        checkIcon(app, row + 1, col - 1, buttons);

        if ~strcmp(buttons{row + 1, col}.Tag, 'open') && buttons{row + 1, col}.Tag == '0'
            buttons{row + 1, col}.Tag = 'open';
            revealEmpty(app, row + 1, col, buttons);
        end
        checkIcon(app, row + 1, col, buttons);
    elseif (row == size(buttons, 1) && col == size(buttons, 2))
        % Bottom-right corner case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col - 1}.Tag, 'open') && buttons{row, col - 1}.Tag == '0'
            buttons{row, col - 1}.Tag = 'open';
            revealEmpty(app, row, col - 1, buttons);
        end
        checkIcon(app, row, col - 1, buttons);

        if ~strcmp(buttons{row - 1, col - 1}.Tag, 'open') && buttons{row - 1, col - 1}.Tag == '0'
            buttons{row - 1, col - 1}.Tag = 'open';
            revealEmpty(app, row - 1, col - 1, buttons);
        end
        checkIcon(app, row - 1, col - 1, buttons);

        if ~strcmp(buttons{row - 1, col}.Tag, 'open') && buttons{row - 1, col}.Tag == '0'
            buttons{row - 1, col}.Tag = 'open';
            revealEmpty(app, row - 1, col, buttons);
        end
        checkIcon(app, row - 1, col, buttons);
    elseif (row == size(buttons, 1) && col == 1)
        % Bottom-left corner case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col + 1}.Tag, 'open') && buttons{row, col + 1}.Tag == '0'
            buttons{row, col + 1}.Tag = 'open';
            revealEmpty(app, row, col + 1, buttons);
        end
        checkIcon(app, row, col + 1, buttons);

        if ~strcmp(buttons{row - 1, col + 1}.Tag, 'open') && buttons{row - 1, col + 1}.Tag == '0'
            buttons{row - 1, col + 1}.Tag = 'open';
            revealEmpty(app, row - 1, col + 1, buttons);
        end
        checkIcon(app, row - 1, col + 1, buttons);

        if ~strcmp(buttons{row - 1, col}.Tag, 'open') && buttons{row - 1, col}.Tag == '0'
            buttons{row - 1, col}.Tag = 'open';
            revealEmpty(app, row - 1, col, buttons);
        end
        checkIcon(app, row - 1, col, buttons);
    elseif (row == 1)
        % Top row case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col - 1}.Tag, 'open') && buttons{row, col - 1}.Tag == '0'
            buttons{row, col - 1}.Tag = 'open';
            revealEmpty(app, row, col - 1, buttons);
        end
        checkIcon(app, row, col - 1, buttons);

        if ~strcmp(buttons{row, col + 1}.Tag, 'open') && buttons{row, col + 1}.Tag == '0'
            buttons{row, col + 1}.Tag = 'open';
            revealEmpty(app, row, col + 1, buttons);
        end
        checkIcon(app, row, col + 1, buttons);

        if ~strcmp(buttons{row + 1, col - 1}.Tag, 'open') && buttons{row + 1, col - 1}.Tag == '0'
            buttons{row + 1, col - 1}.Tag = 'open';
            revealEmpty(app, row + 1, col - 1, buttons);
        end
        checkIcon(app, row + 1, col - 1, buttons);

        if ~strcmp(buttons{row + 1, col}.Tag, 'open') && buttons{row + 1, col}.Tag == '0'
            buttons{row + 1, col}.Tag = 'open';
            revealEmpty(app, row + 1, col, buttons);
        end
        checkIcon(app, row + 1, col, buttons);

        if ~strcmp(buttons{row + 1, col + 1}.Tag, 'open') && buttons{row + 1, col + 1}.Tag == '0'
            buttons{row + 1, col + 1}.Tag = 'open';
            revealEmpty(app, row + 1, col + 1, buttons);
        end
        checkIcon(app, row + 1, col + 1, buttons);
    elseif (row == size(buttons, 1))
        % Bottom row case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row, col - 1}.Tag, 'open') && buttons{row, col - 1}.Tag == '0'
            buttons{row, col - 1}.Tag = 'open';
            revealEmpty(app, row, col - 1, buttons);
        end
        checkIcon(app, row, col - 1, buttons);

        if ~strcmp(buttons{row, col + 1}.Tag, 'open') && buttons{row, col + 1}.Tag == '0'
            buttons{row, col + 1}.Tag = 'open';
            revealEmpty(app, row, col + 1, buttons);
        end
        checkIcon(app, row, col + 1, buttons);

        if ~strcmp(buttons{row - 1, col - 1}.Tag, 'open') && buttons{row - 1, col - 1}.Tag == '0'
            buttons{row - 1, col - 1}.Tag = 'open';
            revealEmpty(app, row - 1, col - 1, buttons);
        end
        checkIcon(app, row - 1, col - 1, buttons);

        if ~strcmp(buttons{row - 1, col}.Tag, 'open') && buttons{row - 1, col}.Tag == '0'
            buttons{row - 1, col}.Tag = 'open';
            revealEmpty(app, row - 1, col, buttons);
        end
        checkIcon(app, row - 1, col, buttons);

        if ~strcmp(buttons{row - 1, col + 1}.Tag, 'open') && buttons{row - 1, col + 1}.Tag == '0'
            buttons{row - 1, col + 1}.Tag = 'open';
            revealEmpty(app, row - 1, col + 1, buttons);
        end
        checkIcon(app, row - 1, col + 1, buttons);
    elseif (col == 1)
        % Leftmost column case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row + 1, col}.Tag, 'open') && buttons{row + 1, col}.Tag == '0'
            buttons{row + 1, col}.Tag = 'open';
            revealEmpty(app, row + 1, col, buttons);
        end
        checkIcon(app, row + 1, col, buttons);

        if ~strcmp(buttons{row + 1, col + 1}.Tag, 'open') && buttons{row + 1, col + 1}.Tag == '0'
            buttons{row + 1, col + 1}.Tag = 'open';
            revealEmpty(app, row + 1, col + 1, buttons);
        end
        checkIcon(app, row + 1, col + 1, buttons);

        if ~strcmp(buttons{row, col + 1}.Tag, 'open') && buttons{row, col + 1}.Tag == '0'
            buttons{row, col + 1}.Tag = 'open';
            revealEmpty(app, row, col + 1, buttons);
        end
        checkIcon(app, row, col + 1, buttons);

        if ~strcmp(buttons{row - 1, col + 1}.Tag, 'open') && buttons{row - 1, col + 1}.Tag == '0'
            buttons{row - 1, col + 1}.Tag = 'open';
            revealEmpty(app, row - 1, col + 1, buttons);
        end
        checkIcon(app, row - 1, col + 1, buttons);

        if ~strcmp(buttons{row - 1, col}.Tag, 'open') && buttons{row - 1, col}.Tag == '0'
            buttons{row - 1, col}.Tag = 'open';
            revealEmpty(app, row - 1, col, buttons);
        end
        checkIcon(app, row - 1, col, buttons);
    elseif (col == size(buttons, 2))
        % Rightmost column case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row + 1, col}.Tag, 'open') && buttons{row + 1, col}.Tag == '0'
            buttons{row + 1, col}.Tag = 'open';
            revealEmpty(app, row + 1, col, buttons);
        end
        checkIcon(app, row + 1, col, buttons);

        if ~strcmp(buttons{row + 1, col - 1}.Tag, 'open') && buttons{row + 1, col - 1}.Tag == '0'
            buttons{row + 1, col - 1}.Tag = 'open';
            revealEmpty(app, row + 1, col - 1, buttons);
        end
        checkIcon(app, row + 1, col - 1, buttons);

        if ~strcmp(buttons{row, col - 1}.Tag, 'open') && buttons{row, col - 1}.Tag == '0'
            buttons{row, col - 1}.Tag = 'open';
            revealEmpty(app, row, col - 1, buttons);
        end
        checkIcon(app, row, col - 1, buttons);

        if ~strcmp(buttons{row - 1, col - 1}.Tag, 'open') && buttons{row - 1, col - 1}.Tag == '0'
            buttons{row - 1, col - 1}.Tag = 'open';
            revealEmpty(app, row - 1, col - 1, buttons);
        end
        checkIcon(app, row - 1, col - 1, buttons);

        if ~strcmp(buttons{row - 1, col}.Tag, 'open') && buttons{row - 1, col}.Tag == '0'
            buttons{row - 1, col}.Tag = 'open';
            revealEmpty(app, row - 1, col, buttons);
        end
        checkIcon(app, row - 1, col, buttons);
    else
        % General case
        % Check and reveal neighboring cells
        if ~strcmp(buttons{row,col+1}.Tag,'open') && buttons{row,col+1}.Tag == '0'
            buttons{row,col+1}.Tag = 'open';
            revealEmpty(app,row,col+1,buttons);
        end
        checkIcon(app,row,col+1,buttons);
        
        if ~strcmp(buttons{row+1,col-1}.Tag,'open') && buttons{row+1,col-1}.Tag == '0'
            buttons{row+1,col-1}.Tag = 'open';
            revealEmpty(app,row+1,col-1,buttons);
        end
        checkIcon(app,row+1,col-1,buttons);
        
        if ~strcmp(buttons{row+1,col}.Tag,'open') && buttons{row+1,col}.Tag == '0'
            buttons{row+1,col}.Tag = 'open';
            revealEmpty(app,row+1,col,buttons);
        end
        checkIcon(app,row+1,col,buttons);
        
        if ~strcmp(buttons{row+1,col+1}.Tag,'open') && buttons{row+1,col+1}.Tag == '0' 
            buttons{row+1,col+1}.Tag = 'open';
            revealEmpty(app,row+1,col+1,buttons);
        end
        checkIcon(app,row+1,col+1,buttons);
        
        if ~strcmp(buttons{row-1,col-1}.Tag,'open') && buttons{row-1,col-1}.Tag == '0'
            buttons{row-1,col-1}.Tag = 'open';
            revealEmpty(app,row-1,col-1,buttons);
        end
        checkIcon(app,row-1,col-1,buttons);
        
        if ~strcmp(buttons{row-1,col}.Tag,'open') && buttons{row-1,col}.Tag == '0'
            buttons{row-1,col}.Tag = 'open';
            revealEmpty(app,row-1,col,buttons);
        end
        checkIcon(app,row-1,col,buttons);
        
        if ~strcmp(buttons{row-1,col+1}.Tag,'open') && buttons{row-1,col+1}.Tag == '0'
            buttons{row-1,col+1}.Tag = 'open';
            revealEmpty(app,row-1,col+1,buttons);
        end
        checkIcon(app,row-1,col+1,buttons);
        
        if ~strcmp(buttons{row,col-1}.Tag,'open') && buttons{row,col-1}.Tag == '0' 
            buttons{row,col-1}.Tag = 'open';
            revealEmpty(app,row,col-1,buttons);
        end
        checkIcon(app,row,col-1,buttons);
    end
end

function checkIcon(~, row, col, buttons)
    % Switch case to set the icon based on the tag value
    switch buttons{row, col}.Tag
        case '1'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number1.png';
        case '2'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number2.png';
        case '3'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number3.png';
        case '4'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number4.png';
        case '5'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number5.png';
        case '6'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number6.png';
        case '7'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number7.png';
        case '8'
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number8.png';
        otherwise
            buttons{row, col}.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\number0.png';
    end
end

     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup function for initializing app properties

            app.panel1.Visible='off';
            app.panel1.BorderColor=[0.50,0.50,0.50];
            app.panel1.ForegroundColor=[0.50,0.50,0.50];
            app.panel1.HighlightColor=[0.50,0.50,0.50]; 
            app.Minefield.BackgroundColor=[0.65,0.65,0.65];
            
        end

        % Button pushed function: StartButton
        function StartGame(app, event)
            % Button pushed function for starting the game
            app.countMines='';
            difficulty=app.ChoosedifficultyDropDown.Value;
            
            
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                  
                case 'Hard'
                    field=zeros(30,16);
                    generateBomb(app,field,99);
                    
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
                    
            end
            %app.MinesweeperUIFigure.Visible='off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MinesweeperUIFigure = uifigure('Visible', 'off');
            app.MinesweeperUIFigure.Name = 'Minesweeper';
            app.MinesweeperUIFigure.Icon = fullfile(pathToMLAPP, 'images', 'bomb.png');
            app.ChoosedifficultyDropDownLabel = uilabel(app.MinesweeperUIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.MinesweeperUIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.MinesweeperUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Tag = 'start';
            app.StartButton.Icon = fullfile(pathToMLAPP, 'images', 'bomb.png');
            app.StartButton.Text = 'Start';
            app.panel1 = uipanel(app.MinesweeperUIFigure);
            app.Minefield = uigridlayout(app.panel1);
            app.WelcometoMinesweeperLabel = uilabel(app.MinesweeperUIFigure);
            app.WelcometoMinesweeperLabel.Interpreter = 'html';
            app.WelcometoMinesweeperLabel.Text = 'Welcome to Minesweeper';
            % Show the figure after all components are created
            app.MinesweeperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MinesweeperUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MinesweeperUIFigure)
        end
    end
end